classdef CodeinAppDesigner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UIAxesSpecies           matlab.ui.control.UIAxes
        SpeciesPlot             matlab.ui.control.UIAxes
        Bar                     matlab.ui.control.UIAxes
        UIAxesSubstance         matlab.ui.control.UIAxes
        Evenness                matlab.ui.control.UIAxes
        BasicSettings           matlab.ui.container.Panel
        CrossfeedingCoeffLabel  matlab.ui.control.Label
        CrossFeeding            matlab.ui.control.NumericEditField
        CompetitionCoeffLabel   matlab.ui.control.Label
        Compet                  matlab.ui.control.NumericEditField
        TimeLabel               matlab.ui.control.Label
        Months                  matlab.ui.control.NumericEditField
        ProConsmLabel           matlab.ui.control.Label
        ProConsm                matlab.ui.control.NumericEditField
        SupplyingSMOMsLabel     matlab.ui.control.Label
        SubstAdd                matlab.ui.control.NumericEditField
        InitialSMOMsLabel       matlab.ui.control.Label
        SubstInitial            matlab.ui.control.NumericEditField
        StartButton             matlab.ui.control.Button
        MOVButton               matlab.ui.control.Button
        OperatingSettings       matlab.ui.container.Panel
        ActualSpecies           matlab.ui.control.CheckBox
        Title1                  matlab.ui.control.Label
        SimpsonUseful           matlab.ui.control.CheckBox
        ShannonDiversity        matlab.ui.control.CheckBox
        ShannonUseful           matlab.ui.control.CheckBox
        Title2                  matlab.ui.control.Label
        SimpsonEvenness         matlab.ui.control.CheckBox
        ShannonEvenness         matlab.ui.control.CheckBox
        SuiJiButton             matlab.ui.control.Button
        QuChuButton             matlab.ui.control.Button
        HuHuanButton            matlab.ui.control.Button
        OperatingMonth          matlab.ui.control.NumericEditField
    end

    
    %properties (Access = private)
    %StoragePath
    %Part_StoragePath % Description
    %end
    properties (Access = private)
        Part_StoragePath
        StoragePath
        Number_of_Runs
        Count_Debacle
        Count_Apoptosis
        Number_of_Species
        shannon_diversity
        shannon_evenness
        shannon_useful
        simpson_useful
        simpson_evenness
        SuiJi
        QuChu
        HuHuan
    end
    
    
    methods (Access = public)
        %=============================
        %========以下主运行函数========
        %=============================
        function start(app)            
            %========以下为元胞自动机初始状态设置========
            app.Number_of_Runs=app.Number_of_Runs+1; %记录点击start的次数
            Count_Intervention=0;%初始设置扰动的次数
            species=96; %微生物物种数
            Library=app.Initial_Species(app.Compet.Value,app.ProConsm.Value,app.CrossFeeding.Value);%微生物生产、消耗、CrossFeeding的属性
            n=100; %空间网格的行数和列数
            CA(1:100,1:100)=struct('S',0,'R',0);%S为物种代码
            Chessboard=zeros(n+1);%比实际大一格的矩阵作为演算棋盘（以显示完全）
            ChessboardPosition=zeros([1,1600]);% 挑选1600个点赋初值
            for i=0:19
                ChessboardPosition(1,[(80*i+1):(80*(i+1))])=...
                    [(505*i+1):5:(505*i+100),(505*i+2):5:(505*i+100),...
                    (505*i+102):5:(505*i+201),(505*i+103):5:(505*i+201)];
            end
            Chessboard(ChessboardPosition)=randi(species,[1 1600]); %微生物分布在100*100的棋盘里
            Subst_of_Chessboard=(1/3*app.SubstInitial.Value)*ones([n,n,3]); %棋盘中每一格的初始小分子有机物浓度
            SumSubst_of_Chessboard=zeros(n,n);%预分配内存
            app.Count_Debacle=zeros(n);%元胞崩溃次数预分配内存
            app.Count_Apoptosis=randi(10,n)-1;%元胞凋亡元胞预分配内存
            N=app.Months.Value; %元胞自动机迭代步数
            Sort_of_Species(1:(N+1))=struct('Range',[]);% 预分配内存，以数量从大到小对剩余物种进行排序并存储在按时间分配的struct里
            Memory_of_Chessboard(1:(N+1))=struct('Map',[]);% 预分配内存，按时间顺序存储棋盘上物种种类数据
            Memory_of_Subst_of_Chessboard(1:(N+1))=struct('Map',[]);% 预分配内存，按时间顺序存储棋盘上小分子有机物浓度数据
            %========以下为元胞图案、视频初始状态设置========
            x=linspace(1,101,(n+1));
            y=linspace(1,101,(n+1));
            [X,Y]=meshgrid(x,y);
            set(app.UIAxesSpecies, 'NextPlot', 'replaceChildren');
            Color0329=importdata('Color0329.mat');
            colormap(app.UIAxesSpecies,Color0329);
            colorbar(app.UIAxesSpecies);
            caxis(app.UIAxesSpecies,[0 97]);
            axis(app.UIAxesSpecies,'square',[1 101 1 101]);
            mov(N) = struct('cdata',[],'colormap',[]);
            %========以下为小分子有机物浓度图案初始状态设置========
            x1=linspace(1,100,n);
            y1=linspace(1,100,n);
            [X1,Y1]=meshgrid(x1,y1);
            set(app.UIAxesSubstance, 'NextPlot', 'replaceChildren');
            colormap(app.UIAxesSubstance,gray(100));
            colorbar(app.UIAxesSubstance,'southoutside','Position',...
                [0.05 0.05 0.95 0.01],'AxisLocation','in',"FontSize",6);
            axis(app.UIAxesSubstance,'square');
            %========以下为生成GIF初始设置========
            tempfig = figure('visible','off');
            tempaxes = axes(tempfig);
            set(tempaxes, 'NextPlot', 'replaceChildren');
            colormap(tempaxes,Color0329);
            colorbar(tempaxes);
            caxis(tempaxes,[0 97]);
            axis(tempaxes,'square',[1 101 1 101]);
            SubstSumtempfig=figure('visible','off');
            SubstSumtempaxes = axes(SubstSumtempfig);
            set(SubstSumtempaxes, 'NextPlot', 'replaceChildren');
            colormap(SubstSumtempaxes,gray(100));
            colorbar(SubstSumtempaxes);
            axis(SubstSumtempaxes,'square',[1 101 1 101]);
            Subst1tempfig=figure('visible','off');
            Subst1tempaxes = axes(Subst1tempfig);
            set(Subst1tempaxes, 'NextPlot', 'replaceChildren');
            colormap(Subst1tempaxes,gray(100));
            colorbar(Subst1tempaxes);
            axis(Subst1tempaxes,'square',[1 101 1 101]);
            Subst2tempfig=figure('visible','off');
            Subst2tempaxes = axes(Subst2tempfig);
            set(Subst2tempaxes, 'NextPlot', 'replaceChildren');
            colormap(Subst2tempaxes,gray(100));
            colorbar(Subst2tempaxes);
            axis(Subst2tempaxes,'square',[1 101 1 101]);
            Subst3tempfig=figure('visible','off');
            Subst3tempaxes = axes(Subst3tempfig);
            set(Subst3tempaxes, 'NextPlot', 'replaceChildren');
            colormap(Subst3tempaxes,gray(100));
            colorbar(Subst3tempaxes);
            axis(Subst3tempaxes,'square',[1 101 1 101]);
            %========以下为零时刻元胞的初始值========
            for i=1:n
                for j=1:n
                    CA(i,j).S=Chessboard(i,j);
                    CA(i,j).R=reshape(Subst_of_Chessboard(i,j,:),[1,3]);
                    SumSubst_of_Chessboard(i,j)=sum(Subst_of_Chessboard(i,j,:));
                end
            end
            % ====画初始时刻元胞自动机运行主图案====
            h0=pcolor(app.UIAxesSpecies,X,Y,Chessboard);
            set(h0,'edgecolor','none');%元胞网格去除边缘
            title(app.UIAxesSpecies,'\bf Condition of community');
            % ====画初始时刻元胞自动机小分子有机物浓度图案====
            hh0=pcolor(app.UIAxesSubstance,X1,Y1,SumSubst_of_Chessboard);
            set(hh0,'edgecolor','none');%元胞网格去除边缘
            title(app.UIAxesSubstance,'\bf Initial value of SMOMs');
            pause(1);
            %========以下存储初始图案的数据、GIF和视频========
            Memory_of_Chessboard(1).Map=Chessboard;
            Memory_of_Subst_of_Chessboard(1).Map=SumSubst_of_Chessboard;
            writematrix(Memory_of_Chessboard(1).Map,[app.StoragePath,'\Memory_of_Chessboard',...
                num2str(app.Number_of_Runs),app.Part_StoragePath,'.xlsx'],'Sheet',1);
            writematrix(Memory_of_Subst_of_Chessboard(1).Map,[app.StoragePath,'\Memory_of_Subst_of_Chessboard',...
                num2str(app.Number_of_Runs),app.Part_StoragePath,'.xlsx'],'Sheet',1,'Range','A1:CV100');
            writematrix(Subst_of_Chessboard(:,:,1),[app.StoragePath,'\Memory_of_Subst_of_Chessboard',...
                num2str(app.Number_of_Runs),app.Part_StoragePath,'.xlsx'],'Sheet',1,'Range','A102:CV201');
            writematrix(Subst_of_Chessboard(:,:,2),[app.StoragePath,'\Memory_of_Subst_of_Chessboard',...
                num2str(app.Number_of_Runs),app.Part_StoragePath,'.xlsx'],'Sheet',1,'Range','A203:CV302');
            writematrix(Subst_of_Chessboard(:,:,3),[app.StoragePath,'\Memory_of_Subst_of_Chessboard',...
                num2str(app.Number_of_Runs),app.Part_StoragePath,'.xlsx'],'Sheet',1,'Range','A304:CV403');
            h1=pcolor(tempaxes,X,Y,Chessboard);%在GIF第一帧上画图
            set(h1,'edgecolor','none');%元胞网格去除边缘
            h2=pcolor(SubstSumtempaxes,X1,Y1,SumSubst_of_Chessboard);
            set(h2,'edgecolor','none');
            h3=pcolor(Subst1tempaxes,X1,Y1,Subst_of_Chessboard(:,:,1));
            set(h3,'edgecolor','none');
            h4=pcolor(Subst2tempaxes,X1,Y1,Subst_of_Chessboard(:,:,2));
            set(h4,'edgecolor','none');
            h5=pcolor(Subst3tempaxes,X1,Y1,Subst_of_Chessboard(:,:,3));
            set(h5,'edgecolor','none');
            [Frame_of_ChessboardGIF,Color_of_ChessboardGIF]=rgb2ind(frame2im(getframe(tempaxes)),256);
            imwrite(Frame_of_ChessboardGIF,Color_of_ChessboardGIF,[app.StoragePath,'\Chessboard_GIF',...
                num2str(app.Number_of_Runs),app.Part_StoragePath,'.gif'],'gif','LoopCount',Inf,'DelayTime',0.5);
            [Frame_of_SubstSumChessboardGIF,Color_of_SubstSumChessboardGIF]=rgb2ind(frame2im(getframe(SubstSumtempaxes)),256);
            imwrite(Frame_of_SubstSumChessboardGIF,Color_of_SubstSumChessboardGIF,[app.StoragePath,'\SubstSumChessboard_GIF',...
                num2str(app.Number_of_Runs),app.Part_StoragePath,'.gif'],'gif','LoopCount',Inf,'DelayTime',0.5);
            [Frame_of_Subst1ChessboardGIF,Color_of_Subst1ChessboardGIF]=rgb2ind(frame2im(getframe(Subst1tempaxes)),256);
            imwrite(Frame_of_Subst1ChessboardGIF,Color_of_Subst1ChessboardGIF,[app.StoragePath,'\Subst1Chessboard_GIF',...
                num2str(app.Number_of_Runs),app.Part_StoragePath,'.gif'],'gif','LoopCount',Inf,'DelayTime',0.5);
            [Frame_of_Subst2ChessboardGIF,Color_of_Subst2ChessboardGIF]=rgb2ind(frame2im(getframe(Subst2tempaxes)),256);
            imwrite(Frame_of_Subst2ChessboardGIF,Color_of_Subst2ChessboardGIF,[app.StoragePath,'\Subst2Chessboard_GIF',...
                num2str(app.Number_of_Runs),app.Part_StoragePath,'.gif'],'gif','LoopCount',Inf,'DelayTime',0.5);
            [Frame_of_Subst3ChessboardGIF,Color_of_Subst3ChessboardGIF]=rgb2ind(frame2im(getframe(Subst3tempaxes)),256);
            imwrite(Frame_of_Subst3ChessboardGIF,Color_of_Subst3ChessboardGIF,[app.StoragePath,'\Subst3Chessboard_GIF',...
                num2str(app.Number_of_Runs),app.Part_StoragePath,'.gif'],'gif','LoopCount',Inf,'DelayTime',0.5);
            mov(1)=getframe(tempaxes);
            %====初始设置画每个物种个体数量直方图====
            Sort_of_Species(1).Range=app.Count_Sort_by_Quantity(Chessboard);
            title(app.Bar,'\bf Initial amount of each species');
            bar(app.Bar,Sort_of_Species(1).Range(:,1),Sort_of_Species(1).Range(:,2),'EdgeColor','w');
            set(app.Bar,'xtick',[1:12:96,96]);
            set(app.Bar,'XGrid','on');
            set(app.Bar,'GridColor','k');
            set(app.Bar,'GridAlpha',1);
            %====初始设置画多样性指数折线图====
            app.Number_of_Species=zeros(N+1,1);
            app.Number_of_Species(1)=size(Sort_of_Species(1).Range,1);
            pi_n0=zeros([size(Sort_of_Species(1).Range,1),1]);
            pins0=zeros([size(Sort_of_Species(1).Range,1),1]);
            pi_n20=zeros([size(Sort_of_Species(1).Range,1),1]);
            for ii=1:size(Sort_of_Species(1).Range,1)
                pi_n0(ii) = (Sort_of_Species(1).Range(ii,2))/...
                    (sum(Sort_of_Species(1).Range(:,2)));
                pins0(ii) = pi_n0(ii) .* log(pi_n0(ii));
                pi_n20(ii) = ((Sort_of_Species(1).Range(ii,2))*...
                    (Sort_of_Species(1).Range(ii,2)-1))/...
                    (sum(Sort_of_Species(1).Range(:,2))*...
                    (sum(Sort_of_Species(1).Range(:,2))-1));
            end
            app.shannon_diversity(1) = -sum(pins0);
            app.shannon_evenness(1) = app.shannon_diversity(1)/...
                log(size(Sort_of_Species(1).Range,1));
            app.shannon_useful(1)=exp(app.shannon_diversity(1));
            app.simpson_useful(1) = 1/sum(pi_n20);
            app.simpson_evenness(1) = app.simpson_useful(1)/...
                (size(Sort_of_Species(1).Range,1));
            plotting(app,0);
            %========以下为元胞自动机的迭代步骤========
            for k=1:N
                % ========元胞更替========
                [CA,app.Count_Debacle,app.Count_Apoptosis]=app.UpdateCell...
                    (CA,n,Library,app.SubstAdd.Value,app.Count_Debacle,app.Count_Apoptosis);
                for i=1:n
                    for j=1:n
                        Chessboard(i,j)=CA(i,j).S;
                        Subst_of_Chessboard(i,j,:)=CA(i,j).R;
                        SumSubst_of_Chessboard(i,j)=sum(Subst_of_Chessboard(i,j,:));
                    end
                end
                % ========人为干预========
                if app.SuiJi==true                
                    [Chessboard,Subst_of_Chessboard,CA]=app.ShuffleRandomly(Chessboard,Subst_of_Chessboard,CA,n);
                    app.OperatingMonth.Value=k;
                    Count_Intervention=Count_Intervention+1;
                    writematrix('随机分布',[app.StoragePath,'\Human_Intervention',app.Part_StoragePath,...
                        '.xlsx'],'Range',[char(64+app.Number_of_Runs),num2str(Count_Intervention)]);
                    writematrix(k,[app.StoragePath,'\Human_Intervention',app.Part_StoragePath,...
                        '.xlsx'],'Range',[char(64+app.Number_of_Runs),num2str(Count_Intervention+150)]);
                    app.SuiJi=false;
                end
                if app.QuChu==true
                    [Chessboard,CA]=app.InterDisturbance(Chessboard,CA,n);
                    app.OperatingMonth.Value=k;
                    Count_Intervention=Count_Intervention+1;
                    writematrix('中度干扰',[app.StoragePath,'\Human_Intervention',app.Part_StoragePath,...
                        '.xlsx'],'Range',[char(64+app.Number_of_Runs),num2str(Count_Intervention)]);
                    writematrix(k,[app.StoragePath,'\Human_Intervention',app.Part_StoragePath,...
                        '.xlsx'],'Range',[char(64+app.Number_of_Runs),num2str(Count_Intervention+150)]);
                    app.QuChu=false;
                end
                if app.HuHuan==true
                    [Chessboard,Subst_of_Chessboard,CA]=app.ExchangePosition(Chessboard,Subst_of_Chessboard,CA,n);
                    app.OperatingMonth.Value=k;
                    Count_Intervention=Count_Intervention+1;
                    writematrix('位置互换',[app.StoragePath,'\Human_Intervention',app.Part_StoragePath,...
                        '.xlsx'],'Range',[char(64+app.Number_of_Runs),num2str(Count_Intervention)]);
                    writematrix(k,[app.StoragePath,'\Human_Intervention',app.Part_StoragePath,...
                        '.xlsx'],'Range',[char(64+app.Number_of_Runs),num2str(Count_Intervention+150)]);
                    app.HuHuan=false;
                end
                % ====画元胞自动机运行主图案====
                h=pcolor(app.UIAxesSpecies,X,Y,Chessboard);
                set(h,'edgecolor','none');%元胞网格去除边缘
                title(app.UIAxesSpecies,['\bf Condition of community after',num2str(k),'moment(s)'])
                % ====画元胞自动机小分子有机物浓度图案====
                hh=pcolor(app.UIAxesSubstance,X1,Y1,SumSubst_of_Chessboard);
                set(hh,'edgecolor','none');%元胞网格去除边缘
                title(app.UIAxesSubstance,['\bf Distribution of SMOMs after',num2str(k),'moment(s)']);
                % ========以下存储图案的数据、GIF和视频========
                Memory_of_Chessboard(k+1).Map=Chessboard;
                Memory_of_Subst_of_Chessboard(k+1).Map=SumSubst_of_Chessboard;
                writematrix(Memory_of_Chessboard(k+1).Map,[app.StoragePath,'\Memory_of_Chessboard',...
                    num2str(app.Number_of_Runs),app.Part_StoragePath,'.xlsx'],'Sheet',(k+1));
                writematrix(Memory_of_Subst_of_Chessboard(k+1).Map,[app.StoragePath,'\Memory_of_Subst_of_Chessboard',...
                    num2str(app.Number_of_Runs),app.Part_StoragePath,'.xlsx'],'Sheet',(k+1),'Range','A1:CV100');
                writematrix(Subst_of_Chessboard(:,:,1),[app.StoragePath,'\Memory_of_Subst_of_Chessboard',...
                    num2str(app.Number_of_Runs),app.Part_StoragePath,'.xlsx'],'Sheet',(k+1),'Range','A102:CV201');
                writematrix(Subst_of_Chessboard(:,:,2),[app.StoragePath,'\Memory_of_Subst_of_Chessboard',...
                    num2str(app.Number_of_Runs),app.Part_StoragePath,'.xlsx'],'Sheet',(k+1),'Range','A203:CV302');
                writematrix(Subst_of_Chessboard(:,:,3),[app.StoragePath,'\Memory_of_Subst_of_Chessboard',...
                    num2str(app.Number_of_Runs),app.Part_StoragePath,'.xlsx'],'Sheet',(k+1),'Range','A304:CV403');
                hh1=pcolor(tempaxes,X,Y,Chessboard);
                set(hh1,'edgecolor','none');%元胞网格去除边缘
                hh2=pcolor(SubstSumtempaxes,X1,Y1,SumSubst_of_Chessboard);
                set(hh2,'edgecolor','none');
                hh3=pcolor(Subst1tempaxes,X1,Y1,Subst_of_Chessboard(:,:,1));
                set(hh3,'edgecolor','none');
                hh4=pcolor(Subst2tempaxes,X1,Y1,Subst_of_Chessboard(:,:,2));
                set(hh4,'edgecolor','none');
                hh5=pcolor(Subst3tempaxes,X1,Y1,Subst_of_Chessboard(:,:,3));
                set(hh5,'edgecolor','none');
                [Frame_of_ChessboardGIF,Color_of_ChessboardGIF]=rgb2ind(frame2im(getframe(tempaxes)),256);
                imwrite(Frame_of_ChessboardGIF,Color_of_ChessboardGIF,[app.StoragePath,'\Chessboard_GIF',...
                    num2str(app.Number_of_Runs),app.Part_StoragePath,'.gif'],'gif','WriteMode','append','DelayTime',0.5);
                [Frame_of_SubstSumChessboardGIF,Color_of_SubstSumChessboardGIF]=rgb2ind(frame2im(getframe(SubstSumtempaxes)),256);
                imwrite(Frame_of_SubstSumChessboardGIF,Color_of_SubstSumChessboardGIF,[app.StoragePath,'\SubstSumChessboard_GIF',...
                    num2str(app.Number_of_Runs),app.Part_StoragePath,'.gif'],'gif','WriteMode','append','DelayTime',0.5);
                [Frame_of_Subst1ChessboardGIF,Color_of_Subst1ChessboardGIF]=rgb2ind(frame2im(getframe(Subst1tempaxes)),256);
                imwrite(Frame_of_Subst1ChessboardGIF,Color_of_Subst1ChessboardGIF,[app.StoragePath,'\Subst1Chessboard_GIF',...
                    num2str(app.Number_of_Runs),app.Part_StoragePath,'.gif'],'gif','WriteMode','append','DelayTime',0.5);
                [Frame_of_Subst2ChessboardGIF,Color_of_Subst2ChessboardGIF]=rgb2ind(frame2im(getframe(Subst2tempaxes)),256);
                imwrite(Frame_of_Subst2ChessboardGIF,Color_of_Subst2ChessboardGIF,[app.StoragePath,'\Subst2Chessboard_GIF',...
                    num2str(app.Number_of_Runs),app.Part_StoragePath,'.gif'],'gif','WriteMode','append','DelayTime',0.5);
                [Frame_of_Subst3ChessboardGIF,Color_of_Subst3ChessboardGIF]=rgb2ind(frame2im(getframe(Subst3tempaxes)),256);
                imwrite(Frame_of_Subst3ChessboardGIF,Color_of_Subst3ChessboardGIF,[app.StoragePath,'\Subst3Chessboard_GIF',...
                    num2str(app.Number_of_Runs),app.Part_StoragePath,'.gif'],'gif','WriteMode','append','DelayTime',0.5);
                mov(k+1)=getframe(tempaxes);
                %====画每个物种个体数量直方图====
                Sort_of_Species(k+1).Range=app.Count_Sort_by_Quantity(Chessboard);
                title(app.Bar,['\bf Amount of each species after',num2str(k),'moment(s)'])
                bar(app.Bar,Sort_of_Species(k+1).Range(:,1),Sort_of_Species(k+1).Range(:,2),'EdgeColor','w');
                set(app.Bar,'xtick',[1:12:96,96]);
                set(app.Bar,'XGrid','on');
                set(app.Bar,'GridColor','k');
                set(app.Bar,'GridAlpha',1);
                %====画物种体数量折线图====
                app.Number_of_Species(k+1)=size(Sort_of_Species(k+1).Range,1);
                pi_n=zeros([size(app.Number_of_Species(k+1),1),1]);
                pins=zeros([size(app.Number_of_Species(k+1),1),1]);
                pi_n2=zeros([size(app.Number_of_Species(k+1),1),1]);
                %====画多样性指数折线图====
                for ii=1:app.Number_of_Species(k+1)
                    pi_n(ii)=(Sort_of_Species(k+1).Range(ii,2))/...
                        (sum(Sort_of_Species(k+1).Range(:,2)));
                    pins(ii) = pi_n(ii)*log(pi_n(ii));
                    pi_n2(ii) = ((Sort_of_Species(k+1).Range(ii,2))*...
                        (Sort_of_Species(k+1).Range(ii,2)-1))/...
                        (sum(Sort_of_Species(k+1).Range(:,2))*...
                        (sum(Sort_of_Species(k+1).Range(:,2))-1));
                end
                app.shannon_diversity(k+1) = -sum(pins);
                app.shannon_evenness(k+1) = app.shannon_diversity(k+1)/...
                    log(size(Sort_of_Species(k+1).Range,1));
                app.shannon_useful(k+1)=exp(app.shannon_diversity(k+1));
                app.simpson_useful(k+1) = 1/sum(pi_n2);
                app.simpson_evenness(k+1) = app.simpson_useful(k+1)/...
                    (size(Sort_of_Species(k+1).Range,1));
                plotting(app,k);
            end
            %========以下存储整体运行条件========
            Set_Condition=[app.SubstInitial.Value;app.SubstAdd.Value;app.ProConsm.Value;...
                app.Months.Value;app.Compet.Value;app.CrossFeeding.Value];
            writematrix(Set_Condition,[app.StoragePath,'\Set_Condition',...
                app.Part_StoragePath,'.xlsx'],'Range',...
                [char(65+app.Number_of_Runs),'1',':',char(65+app.Number_of_Runs),'6'])
            %========以下存储整体实验数据========
            writematrix(app.Number_of_Species,[app.StoragePath,'\Number_of_Species',...
                app.Part_StoragePath,'.xlsx'],'Range',[char(64+app.Number_of_Runs),'1',...
                ':',char(64+app.Number_of_Runs),num2str(app.Months.Value+1)]);
            writematrix((app.simpson_useful)',[app.StoragePath,'\simpson_useful',...
                app.Part_StoragePath,'.xlsx'],'Range',[char(64+app.Number_of_Runs),'1',...
                ':',char(64+app.Number_of_Runs),num2str(app.Months.Value+1)]);
            writematrix((app.shannon_useful)',[app.StoragePath,'\shannon_useful',...
                app.Part_StoragePath,'.xlsx'],'Range',[char(64+app.Number_of_Runs),'1',...
                ':',char(64+app.Number_of_Runs),num2str(app.Months.Value+1)]);
            writematrix((app.shannon_diversity)',[app.StoragePath,'\shannon_diversity',...
                app.Part_StoragePath,'.xlsx'],'Range',[char(64+app.Number_of_Runs),'1',...
                ':',char(64+app.Number_of_Runs),num2str(app.Months.Value+1)]);
            writematrix((app.simpson_evenness)',[app.StoragePath,'\simpson_evenness',...
                app.Part_StoragePath,'.xlsx'],'Range',[char(64+app.Number_of_Runs),'1',...
                ':',char(64+app.Number_of_Runs),num2str(app.Months.Value+1)]);
            writematrix((app.shannon_evenness)',[app.StoragePath,'\shannon_evenness',...
                app.Part_StoragePath,'.xlsx'],'Range',[char(64+app.Number_of_Runs),'1',...
                ':',char(64+app.Number_of_Runs),num2str(app.Months.Value+1)]);
            %========以下存储整体视频数据========
            save([app.StoragePath,'\Chessboard_MOV',num2str(app.Number_of_Runs),app.Part_StoragePath,'.mat'],'mov');
            %========以下清空private变量========
            app.Count_Debacle=[];
            app.Count_Apoptosis=[];
            app.Number_of_Species=[];
            app.shannon_diversity=[];
            app.shannon_evenness=[];
            app.shannon_useful=[];
            app.simpson_useful=[];
            app.simpson_evenness=[];
        end
        %=============================
        %========以下为画图函数========
        %=============================
        function plotting(app,k)
            yyaxis(app.SpeciesPlot,'left')
            SpeciesLegend={};
            if app.ActualSpecies.Value
                stem(app.SpeciesPlot,(0:k), app.Number_of_Species(1:(k+1)),':.b');
                SpeciesLegend=cat(2,SpeciesLegend,'Actual number of species');
            end
            hold(app.SpeciesPlot,'on')
            if app.SimpsonUseful.Value
                plot(app.SpeciesPlot,(0:k), app.simpson_useful,'-x','color','#00FFFF');
                SpeciesLegend=cat(2,SpeciesLegend,'Simpson effective species');
            end
            if app.ShannonUseful.Value
                plot(app.SpeciesPlot,(0:k), app.shannon_useful,'-x','color','#4DBEEE');
                SpeciesLegend=cat(2,SpeciesLegend,'Shannon effective species');
            end
            hold(app.SpeciesPlot,'off')
            if app.ShannonDiversity.Value
                yyaxis(app.SpeciesPlot,'right')
                plot(app.SpeciesPlot,(0:k), app.shannon_diversity,'-o','color','#D95319');
                SpeciesLegend=cat(2,SpeciesLegend,'Shannon diversity index');
            end
            legend(app.SpeciesPlot,SpeciesLegend(1:end));
            legend(app.SpeciesPlot,'boxoff');
            
            EvennessLegend={};
            if app.SimpsonEvenness.Value
                plot(app.Evenness,(0:k), app.simpson_evenness,'-*','color','#00FFFF');
                EvennessLegend=cat(2,EvennessLegend,'Simpson evenness index');
            end
            hold(app.Evenness,'on')
            if app.ShannonEvenness.Value
                plot(app.Evenness,(0:k), app.shannon_evenness,'-*','color','#4DBEEE');
                EvennessLegend=cat(2,EvennessLegend,'Shannon evenness index');
            end
            hold(app.Evenness,'off')
            legend(app.Evenness,EvennessLegend(1:end));
            legend(app.Evenness,'boxoff');
        end
        
        
    end
    
    %=============================
    %=============================
    %========以下为静态函数========
    %=============================
    %=============================
    
    methods (Static)
%=====================================获取邻居=====================================
        function [in, jn] = GetNeigbr(i, j, n)
            % ====本函数根据元胞自己的索引，确定其邻居的索引，采用周期边界=====
            % =====i, j 为元胞自己的索引，n 为行数或列数======
            zs = [i-1, j-1];       s = [i-1, j];       ys = [i-1, j+1]; % 上面的邻居
            z =   [i, j-1]; y = [i,   j+1];   %  左右的邻居
            zx = [i+1, j-1];   x = [i+1, j];   yx = [i+1, j+1];  % 下面的邻居
            Raw_Local = [zx; x; yx; z; y; zs; s; ys]; % 8 个邻居的位置
            Sort_Local = Raw_Local(:);
            for neighborcount = 1: length(Sort_Local)
                if Sort_Local(neighborcount)<1; Sort_Local(neighborcount) = n;
                elseif Sort_Local(neighborcount)>n; Sort_Local(neighborcount) = 1;
                end
            end
            Final_Local = reshape(Sort_Local, 8, 2);
            [in, jn]= deal(Final_Local(:,1), Final_Local(:,2));	%相当于 in=Final_Local(:,1),jn=Final_Local(:,2)
        end
        
        
%=====================================排序=====================================
        function Temp_Sort_of_Species=Count_Sort_by_Quantity(Chessboard)
            %========计数并按照数量大小排序========
            Sort_of_Chessboard=sort(Chessboard(:));
            Temp_Sort_of_Species=[];
            while 1
                Counter_of_Sort=0;
                Record_of_Species=Sort_of_Chessboard(1);
                for j=1:length(Sort_of_Chessboard)
                    if Record_of_Species==Sort_of_Chessboard(j)
                        Counter_of_Sort=Counter_of_Sort+1;
                    end
                end
                Temp_Sort_of_Species=cat(1,Temp_Sort_of_Species,...
                    [Record_of_Species,Counter_of_Sort]);
                Temp_Sort_of_Species(Temp_Sort_of_Species(:,1)==0,:)=[];
                Sort_of_Chessboard(1:Counter_of_Sort)=[];
                if isempty(Sort_of_Chessboard)
                    break;
                end
            end
        end
        
%=====================================元胞更新=====================================
        function [Ct,Count_Debacle,Count_Apoptosis]=UpdateCell(CA,n,Library,SubstAdd,Count_Debacle,Count_Apoptosis)
            % =====初始化===============
            CAA(1:100,1:100)=struct('S',0,'R',0,'CF',0);
            for i = 1:n
                for j = 1:n
                    CAA(i,j).S=CA(i,j).S;
                    CAA(i,j).R=zeros([1,3]);
                    CAA(i,j).CF=zeros([1,3]);
                end
            end
            SubstNeigb=zeros(n,n,3);
            CompetNeigb=zeros(n,n,3);
            Calculate_for_CF=zeros(n,n,3);
            Calculate_for_end=zeros(n,n,3);
            %========判断是否有CF=========
            for i = 1:n
                for j = 1:n
                    %========获取邻居========
                    [in, jn] = Version0505_Final.GetNeigbr(i, j, n);       % 棋盘上(i,j)邻居的索引，in和jn分别是列向量
                    neigbsubt = [];
                    neigbcompet=[];
                    for nc = 1:8
                        neigbsubt = cat(1, neigbsubt,CA(in(nc),jn(nc)).R);
                        [inn,jnn]=Version0505_Final.GetNeigbr(in(nc),jn(nc),n);% 棋盘上(in(nc),jn(nc))邻居的索引，in(nc)和jn(nc)分别是列向量
                        nneigbsubt=[];
                        nneigbspecies=[];
                        for nnc=1:8
                            nneigbsubt=cat(1, nneigbsubt,CA(inn(nnc),jnn(nnc)).R);%棋盘上(in(nc),jn(nc))邻居小分子有机物浓度
                            nneigbspecies=cat(1, nneigbspecies,CA(inn(nnc),jnn(nnc)).S);
                        end
                        if CA(in(nc),jn(nc)).S==0
                            neigbcompet=cat(1,neigbcompet,[0 0 0]);
                        else
                            neigbcompet=cat(1,neigbcompet,0.125*...
                                (Library(CA(in(nc),jn(nc)).S).Compet)*sum(nneigbsubt));%先求(in(nc),jn(nc))邻居小分子有机物浓度之和，折算为(in(nc),jn(nc))需要的，再除以8
                        end
                    end
                    SubstNeigb(i,j,(1:3)) =sum(neigbsubt);   %计算(i,j)邻居现存小分子有机物浓度之和
                    CompetNeigb(i,j,(1:3))=sum(neigbcompet);%计算 (i,j)的邻居 消耗 (i,j)的小分子有机物浓度 之和
                    if CA(i,j).S>0 %此位置上有微生物
                        for Subst=1:3
                            Calculate_for_CF(i,j,Subst)=(Library(CA(i,j).S).SubstPro(Subst)-Library(CA(i,j).S).SubstConsm(Subst)+...
                                CA(i,j).R(Subst)+(Library(CA(i,j).S).Compet)*SubstNeigb(i,j,Subst)-CompetNeigb(i,j,Subst))+1/3*SubstAdd;
                            if Calculate_for_CF(i,j,Subst)>0
                                CAA(i,j).CF(Subst)=(Library(CA(i,j).S).CrossFeeding)*Calculate_for_CF(i,j,Subst);
                            end
                        end
                    end
                end
            end            
            %========元胞更替========
            for i=1:n
                for j=1:n
                    [in, jn] = Version0505_Final.GetNeigbr(i, j, n);
                    neigbCF=[];
                    for nc=1:8
                        neigbCF=cat(1,neigbCF,CAA(i,j).CF);
                    end
                    sumneigbCF=sum(neigbCF);
                    Raw_Neigb = [];
                    for nc2 = 1:8
                       Raw_Neigb = cat(1, Raw_Neigb, CA(in(nc2),jn(nc2)).S);                      
                    end
                    if CA(i,j).S== 0     % 表示此位置上没有微生物
                        if all(Raw_Neigb == 0)             % 表示周围邻居位置中没有物种占领
                            CAA(i,j).S = 0;
                            CAA(i,j).R=CA(i,j).R+1/3*SubstAdd*ones([1,3]);
                            Count_Debacle(i,j)=0;
                            Count_Apoptosis(i,j)=0;
                        else
                            Sort_Neigb = Raw_Neigb;
                            Sort_Neigb(Sort_Neigb==0) =[];  % 把为物种 0 的位置去除
                            Raw_Invader = randi([1, length(Sort_Neigb)],1,1); % 表示从周围邻居任找一物种占领此位置
                            Invader = find(Raw_Neigb==Sort_Neigb(Raw_Invader));
                            CAA(i,j).S= CA(in(Invader(1)), jn(Invader(1))).S;
                            CAA(i,j).R=CA(i,j).R+1/3*SubstAdd*ones([1,3]);
                            Count_Debacle(i,j)=0;
                            Count_Apoptosis(i,j)=0;
                        end
                    else
                        Count_Small=0;
                        Count_Apoptosis(i,j)=Count_Apoptosis(i,j)+1;
                        for Subst=1:3
                            Calculate_for_end(i,j,Subst)=Library(CA(i,j).S).SubstPro(Subst)-...
                                Library(CA(i,j).S).SubstConsm(Subst)+CA(i,j).R(Subst)...
                                +(Library(CA(i,j).S).Compet)*0.125*...
                                SubstNeigb(i,j,Subst)-CompetNeigb(i,j,Subst)+0.125...
                                *(sumneigbCF(Subst))-CAA(i,j).CF(Subst)+1/3*SubstAdd;
                            if (Calculate_for_end(i,j,Subst)<0)%记录每一样小分子有机物是否缺少
                                Count_Small=Count_Small+1;
                                CAA(i,j).R(Subst)=0;
                            else
                                CAA(i,j).R(Subst)=Calculate_for_end(i,j,Subst);
                            end
                        end
                        if Count_Small>0%如果有一样缺少就记录为崩溃可能性+1
                            Count_Debacle(i,j)=Count_Debacle(i,j)+1;
                        else
                            Count_Debacle(i,j)=0;%否则就重置崩溃可能性
                        end
                        if (Count_Debacle(i,j)==3)%崩溃可能性达到3时崩溃
                            CAA(i,j).S=0;
                            CAA(i,j).R=zeros([1,3]);
                        end
                        if (Count_Apoptosis(i,j)==10) %自然生存10月后死亡
                            CAA(i,j).S=0;
                        end
                    end
                end
            end
            CAA=rmfield(CAA,'CF');
            Ct=CAA;
        end
        
        
%=====================================初始化微生物属性=====================================        
        function Lib=Initial_Species(Compet,ProConsm,CrossFeeding)
            Lib(1:96)=struct('SubstPro',0,'SubstConsm',0,'Compet',0,'CrossFeeding',0);%96种微生物物种的属性档案
            TempMatrix=ProConsm*random('Poisson',4,96,6);
            TempMatrix(:,7)=Compet*rand(96,1);
            TempMatrix(:,8)=CrossFeeding*rand(96,1);
            TempMatrix(:,9)=(TempMatrix(:,1)+TempMatrix(:,2)+TempMatrix(:,3)-TempMatrix(:,4)-TempMatrix(:,5)-TempMatrix(:,6));
            for i=1:8
                Temp12Matrix=TempMatrix(((12*(i-1)+1):12*i),:);
                Temp12Matrix=sortrows(Temp12Matrix,9,"descend");
                if (i==4)||(i==6)||(i==7)||(i==8)
                    Temp12Matrix(:,1)=0;
                end
                if (i==3)||(i==5)||(i==7)||(i==8)
                    Temp12Matrix(:,2)=0;
                end
                if (i==2)||(i==5)||(i==6)||(i==8)
                    Temp12Matrix(:,3)=0;
                end
                for j=(12*(i-1)+1):(12*i)
                    k=mod(j,12);
                    if k==0
                        k=12;
                    end
                    Lib(j).SubstPro=Temp12Matrix(k,(1:3));
                    Lib(j).SubstConsm=Temp12Matrix(k,(4:6));
                    Lib(j).Compet=Temp12Matrix(k,7);
                    Lib(j).CrossFeeding=Temp12Matrix(k,8);
                end
            end
        end
%=====================================随机分布=====================================        
        function [Chessboard,Subst_of_Chessboard,CA]=ShuffleRandomly(Chessboard,Subst_of_Chessboard,CA,n)
            Chessboard100=Chessboard(1:100,1:100);%将101*101的棋盘中100*100的有元胞的抠出来
            Subst_of_Chessboard1=Subst_of_Chessboard(:,:,1);
            Subst_of_Chessboard2=Subst_of_Chessboard(:,:,2);
            Subst_of_Chessboard3=Subst_of_Chessboard(:,:,3);
            TempChessboard=zeros(100);%赋初值
            TempSubst_of_Chessboard1=zeros(n);
            TempSubst_of_Chessboard2=zeros(n);
            TempSubst_of_Chessboard3=zeros(n);
            RandChessboard=randperm(10000);%赋随机数
            TempChessboard(1:end)=Chessboard100(RandChessboard);%随机打乱
            TempSubst_of_Chessboard1(1:end)=Subst_of_Chessboard1(RandChessboard);
            TempSubst_of_Chessboard2(1:end)=Subst_of_Chessboard2(RandChessboard);
            TempSubst_of_Chessboard3(1:end)=Subst_of_Chessboard3(RandChessboard);
            Chessboard(1:100,1:100)=TempChessboard;%将随机打乱后的值赋回去
            Subst_of_Chessboard(:,:,1)=TempSubst_of_Chessboard1;
            Subst_of_Chessboard(:,:,2)=TempSubst_of_Chessboard2;
            Subst_of_Chessboard(:,:,3)=TempSubst_of_Chessboard3;
            for i=1:n
                for j=1:n
                    CA(i,j).S=Chessboard(i,j);%将棋盘赋到元胞中
                    CA(i,j).R=reshape(Subst_of_Chessboard(i,j,:),[1,3]);
                end
            end
        end
        
%=====================================中度干扰=====================================        
        function [Chessboard,CA]=InterDisturbance(Chessboard,CA,n)
            Chessboard100=Chessboard(1:100,1:100);%将101*101的棋盘中100*100的有元胞的抠出来
            x1=randi(100);
            y1=randi(100);
            x2=randi(100);
            y2=randi(100);
            Chessboard100([min(y1,y2):min(max(y1,y2),(min(y1,y2)+49))],[min(x1,x2):min(max(x1,x2),(min(x1,x2)+49))])=0;
            Chessboard(1:100,1:100)=Chessboard100;
            for i=1:n
                for j=1:n
                    CA(i,j).S=Chessboard(i,j);%将棋盘赋到元胞中
                end
            end
        end
        
%=====================================位置互换=====================================
        function [Chessboard,Subst_of_Chessboard,CA]=ExchangePosition(Chessboard,Subst_of_Chessboard,CA,n)
            Chessboard100=Chessboard(1:100,1:100);%将101*101的棋盘中100*100的有元胞的抠出来
            Size=4;
            Gap=Size-1;
            Count_Repeat=0;
            Storage_Species=[];
            Storage_Position=[];
            Storage_Substance=[];%初始化
            for i=1:n
                for j=1:n
                    if (i<(100-Gap+1))&&(j<(100-Gap+1))
                        if all(all(Chessboard100([i:(i+Gap)],[j:(j+Gap)])==Chessboard100(i,j)))
                            Storage_Species=cat(1,Storage_Species,Chessboard100(i,j));%存储物种类别，位置，小分子有机物浓度
                            Storage_Position=cat(1,Storage_Position,[i,j]);
                            Storage_Substance=cat(1,Storage_Substance,Subst_of_Chessboard([i:(i+Gap)],[j:(j+Gap)],:));
                            Count_Repeat=Count_Repeat+1;%计次+1
                            Chessboard100([i:(i+Gap)],[j:(j+Gap)])=0;%清空棋盘上的这块微生物，避免重复搜索
                        end
                    elseif (i<(100-Gap+1))&&(j>(100-Gap))
                        if all(all([Chessboard100([i:(i+Gap)],[j:100]),Chessboard100([i:(i+Gap)],[1:(j+Gap-100)])]==Chessboard100(i,j)))
                            Storage_Species=cat(1,Storage_Species,Chessboard100(i,j));
                            Storage_Position=cat(1,Storage_Position,[i,j]);
                            Storage_Substance=cat(1,Storage_Substance,cat(2,Subst_of_Chessboard([i:(i+Gap)],[j:100],:),Subst_of_Chessboard([i:(i+Gap)],[1:(j+Gap-100)],:)));
                            Count_Repeat=Count_Repeat+1;
                            Chessboard100([i:(i+Gap)],[j:100])=0;
                            Chessboard100([i:(i+Gap)],[1:(j+Gap-100)])=0;
                        end
                    elseif (i>(100-Gap))&&(j<(100-Gap+1))
                        if all(all([Chessboard100([i:100],[j:(j+Gap)]);Chessboard100([1:(i+Gap-100)],[j:(j+Gap)])]==Chessboard100(i,j)))
                            Storage_Species=cat(1,Storage_Species,Chessboard100(i,j));
                            Storage_Position=cat(1,Storage_Position,[i,j]);
                            Storage_Substance=cat(1,Storage_Substance,cat(1,Subst_of_Chessboard([i:100],[j:(j+Gap)],:),Subst_of_Chessboard([1:(i+Gap-100)],[j:(j+Gap)],:)));
                            Count_Repeat=Count_Repeat+1;
                            Chessboard100([i:100],[j:(j+Gap)])=0;
                            Chessboard100([1:(i+Gap-100)],[j:(j+Gap)])=0;
                        end
                    else
                        if all(all([Chessboard100([i:100],[j:100]),Chessboard100([i:100],[1:(j+Gap-100)]);[Chessboard100([1:(i+Gap-100)],[j:100]),Chessboard100([1:(i+Gap-100)],[1:(j+Gap-100)])]==Chessboard100(i,j)]))
                            Storage_Species=cat(1,Storage_Species,Chessboard100(i,j));
                            Storage_Position=cat(1,Storage_Position,[i,j]);
                            Storage_Substance=cat(1,Storage_Substance,[Subst_of_Chessboard([i:100],[j:100],:),Subst_of_Chessboard([i:100],[1:(j+Gap-100)],:);[Subst_of_Chessboard([1:(i+Gap-100)],[j:100],:),Subst_of_Chessboard([1:(i+Gap-100)],[1:(j+Gap-100)],:)]]);
                            Count_Repeat=Count_Repeat+1;
                            Chessboard100([i:100],[j:100])=0;
                            Chessboard100([i:100],[1:(j+Gap-100)])=0;
                            Chessboard100([1:(i+Gap-100)],[j:100])=0;
                            Chessboard100([1:(i+Gap-100)],[1:(j+Gap-100)])=0;
                        end
                    end
                end
            end
            RandomSort=randperm(Count_Repeat);
            for Count=1:Count_Repeat
                i=Storage_Position(Count,1);
                j=Storage_Position(Count,2);
                if (i<(100-Gap+1))&&(j<(100-Gap+1))
                    Chessboard100([i:(i+Gap)],[j:(j+Gap)])=Storage_Species(RandomSort(Count));
                    Subst_of_Chessboard([i:(i+Gap)],[j:(j+Gap)],:)=Storage_Substance([(Size*RandomSort(Count)-Gap):(Size*RandomSort(Count))],[1:Size],:);
                elseif (i<(100-Gap+1))&&(j>(100-Gap))
                    Chessboard100([i:(i+Gap)],[j:100])=Storage_Species(RandomSort(Count));
                    Chessboard100([i:(i+Gap)],[1:(j+Gap-100)])=Storage_Species(RandomSort(Count));
                    Subst_of_Chessboard([i:(i+Gap)],[j:100],:)=Storage_Substance([(Size*RandomSort(Count)-Gap):(Size*RandomSort(Count))],[1:(-j+1+100)],:);
                    Subst_of_Chessboard([i:(i+Gap)],[1:(j+Gap-100)],:)=Storage_Substance([(Size*RandomSort(Count)-Gap):(Size*RandomSort(Count))],[(-j+100+2):Size],:);
                elseif (i>(100-Gap))&&(j<(100-Gap+1))
                    Chessboard100([i:100],[j:(j+Gap)])=Storage_Species(RandomSort(Count));
                    Chessboard100([1:(i+Gap-100)],[j:(j+Gap)])=Storage_Species(RandomSort(Count));
                    Subst_of_Chessboard([i:100],[j:(j+Gap)],:)=Storage_Substance([(Size*RandomSort(Count)-Gap):((Size*RandomSort(Count)-Gap)-i+100)],[1:Size],:);
                    Subst_of_Chessboard([1:(i+Gap-100)],[j:(j+Gap)],:)=Storage_Substance([((Size*RandomSort(Count)-Gap)-i+100+1):(Size*RandomSort(Count))],[1:Size],:);
                else
                    Chessboard100([i:100],[j:100])=Storage_Species(RandomSort(Count));
                    Chessboard100([1:(i+Gap-100)],[1:(j+Gap-100)])=Storage_Species(RandomSort(Count));
                    Chessboard100([i:100],[1:(j+Gap-100)])=Storage_Species(RandomSort(Count));
                    Chessboard100([1:(i+Gap-100)],[j:100])=Storage_Species(RandomSort(Count));
                    Subst_of_Chessboard([i:100],[j:100],:)=Storage_Substance([(Size*RandomSort(Count)-Gap):((Size*RandomSort(Count)-Gap)+100-i)],[1:(100-j+1)],:);
                    Subst_of_Chessboard([1:(i+Gap-100)],[1:(j+Gap-100)],:)=Storage_Substance([((Size*RandomSort(Count)-Gap)+100-i+1):(Size*RandomSort(Count))],[(100-j+2):Size],:);
                    Subst_of_Chessboard([i:100],[1:(j+Gap-100)],:)=Storage_Substance([(Size*RandomSort(Count)-Gap):(Size*RandomSort(Count)-Gap-i+100)],[(100-j+2):Size],:);
                    Subst_of_Chessboard([1:(i+Gap-100)],[j:100],:)=Storage_Substance([(Size*RandomSort(Count)-Gap-i+100+1):(Size*RandomSort(Count))],[1:(100-j+1)],:);
                end
            end
            Chessboard([1:100],[1:100])=Chessboard100;
            for i=1:n
                for j=1:n
                    CA(i,j).S=Chessboard(i,j);%将棋盘赋到元胞中
                    CA(i,j).R=reshape(Subst_of_Chessboard(i,j,:),[1,3]);
                end
            end
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ProConsm.Value = 1;
            app.Compet.Value = 0.5;
            app.CrossFeeding.Value=0.5;
            app.SubstInitial.Value = 10;
            app.SubstAdd.Value=10;
            app.Months.Value=36;
            
            app.ActualSpecies.Value=1;
            app.SimpsonUseful.Value=1;
            app.ShannonUseful.Value=1;
            app.ShannonDiversity.Value=1;
            app.ShannonEvenness.Value=1;
            app.SimpsonEvenness.Value=1;
            
            % 初始化坐标区
            title(app.UIAxesSpecies,'Condition of community');
            axis(app.UIAxesSpecies,'square');
            app.UIAxesSpecies.XColor = 'none';
            app.UIAxesSpecies.YColor = 'none';
            title(app.UIAxesSubstance,'Distribution of SMOMs');
            axis(app.UIAxesSubstance,'square');
            app.UIAxesSubstance.XColor = 'none';
            app.UIAxesSubstance.YColor = 'none';
            
            app.SpeciesPlot.Title.String = 'Richness';
            app.SpeciesPlot.XLabel.String = 'Time';
            yyaxis(app.SpeciesPlot,'left')
            app.SpeciesPlot.YLabel.String = 'Living Species';
            yyaxis(app.SpeciesPlot,'right')
            app.SpeciesPlot.YLabel.String = 'Shannon diversity index';
            app.Bar.Title.String = 'Amount of each species';
            app.Bar.XLabel.String = 'Species';
            app.Bar.YLabel.String = 'Amount of individuals';
            app.Evenness.Title.String = 'Evenness';
            app.Evenness.XLabel.String = 'Time';
            app.Evenness.YLabel.String = 'Evenness index';
            
            app.Part_StoragePath=datestr(datetime,30);
            mkdir('D:\毕业设计\仿真实验数据',app.Part_StoragePath); %新建文件夹
            app.StoragePath=['D:\毕业设计\仿真实验数据\',app.Part_StoragePath]; %获取文件夹路径
            app.Number_of_Runs=0;
            Condition_Name=["初始小分子有机物";"补充小分子有机物";"生产与消耗力";"运行时间间隔数";"竞争程度";"交叉馈送程度"];
            writematrix(Condition_Name,[app.StoragePath,'\Set_Condition',...
                app.Part_StoragePath,'.xlsx'],'Range','A1:A6');
            
            %===============更新日志=================
            %0313版更新：1.引用函数从文件夹调至程序内函数。2.将cell类型改为struct类型，方便运算。
            %0314版更新：1.修正物种数量初始值不正确的问题。2.去掉空白计数为物种个体数量的bar。
            %0316版更新：1.修正了UpdateCell中不符合生物学原理的部分。
            %0318版更新：1.更改了颜色配置文件。
            %0328版更新：1.更改了颜色配置文件。2.修复了由于循环语句造成的运算顺序问题。
            %           3.更改了空白元胞占领时底物浓度迁移规则。4.增加了物种属性。
            %0329版更新：1.根据0328的物种属性修改了演替规则。2.更改了颜色配置文件。
            %           3.去掉了底物浓度图的坐标轴。4.固定了个体数量和物种colorbar的间隔为12。
            %           5.添加了个体数量的网格线。
            %0331版更新：1.添加了数据保存的路径。2.修正了主副图标题的错误。
            %0404版更新：1.增加了交叉馈送和竞争共存的机制。2.更换了随机数生成方法。
            %           3.持续增加底物。4.改变了元胞崩溃的机制。5.修正了主图颜色条不准确的错误。
            %           6.修正了竞争系数缺失的错误。7.修正了多样性指数的计算错误。
            %0406版更新：1.更改了用户界面。2.增加了画图界面。
            %0409版更新：1.修正了画图可选项的错误。2.修正了物种多样性指数的算法错误。
            %           3.更正了如果有空白元胞也依然有8个邻居的错误。
            %0411版更新：1.元胞演替规则更改为连续3个时间段不满足崩溃，连续生活10个时间段凋亡。
            %           2.元胞生产力与消耗力的属性分布改为泊松分布。4.修改了底物浓度图的colorbar位置。
            %0412版更新：1.修正了判断CF的公式错误。2.添加了三个人工干预的功能。
            %0413版更新：1.修复了保存功能。
            %0415版更新：1.修复了保存功能。2.给崩溃和凋亡值赋初值。3.只有满足生存条件的才能生殖。
            %0416版更新：1.把元胞崩溃初始值改为均为0。
            %0426版更新：1.修复了空白元胞更新底物的错误。2. 改回占领空白元胞的规则，不必须不崩溃。
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            start(app);
        end

        % Button pushed function: MOVButton
        function MOVButtonPushed(app, event)
            load([app.StoragePath,'\Chessboard_MOV',app.Part_StoragePath,'.mat']);
            implay(mov,1)
        end

        % Value changed function: SubstInitial
        function SubstInitialValueChanged(app, event)
            app.SubstInitial.Value=event.Value;
        end

        % Value changed function: ProConsm
        function ProConsmValueChanged(app, event)
            app.ProConsm.Value=event.Value;
        end

        % Value changed function: Compet
        function CompetValueChanged(app, event)
            app.Compet.Value=event.Value;
        end

        % Value changed function: Months
        function MonthsValueChanged(app, event)
            app.Months.Value=event.Value;
        end

        % Value changed function: SubstAdd
        function SubstAddValueChanged(app, event)
            app.SubstAdd.Value=event.Value;
        end

        % Value changed function: CrossFeeding
        function CrossFeedingValueChanged(app, event)
            app.CrossFeeding.Value=event.Value;
        end

        % Button pushed function: SuiJiButton
        function SuiJiButtonPushed(app, event)
            app.SuiJi=true;
        end

        % Button pushed function: QuChuButton
        function QuChuButtonPushed(app, event)
            app.QuChu=true;
        end

        % Button pushed function: HuHuanButton
        function HuHuanButtonPushed(app, event)
            app.HuHuan=true;
        end

        % Value changed function: OperatingMonth
        function OperatingMonthValueChanged(app, event)
            app.OperatingMonth.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxesSpecies = uiaxes(app.UIFigure);
            title(app.UIAxesSpecies, 'Condition of community')
            xlabel(app.UIAxesSpecies, '')
            ylabel(app.UIAxesSpecies, '')
            app.SpeciesPlot = uiaxes(app.UIFigure);
            title(app.SpeciesPlot, 'Richness')
            xlabel(app.SpeciesPlot, '')
            ylabel(app.SpeciesPlot, '')
            app.Bar = uiaxes(app.UIFigure);
            title(app.Bar, 'Amount of each species')
            xlabel(app.Bar, '')
            ylabel(app.Bar, '')
            app.UIAxesSubstance = uiaxes(app.UIFigure);
            title(app.UIAxesSubstance, 'Distribution of SMOMs')
            xlabel(app.UIAxesSubstance, '')
            ylabel(app.UIAxesSubstance, '')
            app.UIAxesSubstance.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.Evenness = uiaxes(app.UIFigure);
            title(app.Evenness, 'Evenness')
            xlabel(app.Evenness, '')
            ylabel(app.Evenness, '')
            app.BasicSettings = uipanel(app.UIFigure);
            app.BasicSettings.Title = 'Basic Settings';
            app.CrossfeedingCoeffLabel = uilabel(app.BasicSettings);
            app.CrossfeedingCoeffLabel.Text = 'Cross-feeding Coeff';
            app.CrossFeeding = uieditfield(app.BasicSettings, 'numeric');
            app.CrossFeeding.ValueChangedFcn = createCallbackFcn(app, @CrossFeedingValueChanged, true);
            app.CompetitionCoeffLabel = uilabel(app.BasicSettings);
            app.CompetitionCoeffLabel.Text = 'Competition Coeff';
            app.Compet = uieditfield(app.BasicSettings, 'numeric');
            app.Compet.ValueChangedFcn = createCallbackFcn(app, @CompetValueChanged, true);
            app.TimeLabel = uilabel(app.BasicSettings);
            app.TimeLabel.Text = 'Time';
            app.Months = uieditfield(app.BasicSettings, 'numeric');
            app.Months.ValueChangedFcn = createCallbackFcn(app, @MonthsValueChanged, true);
            app.ProConsmLabel = uilabel(app.BasicSettings);
            app.ProConsmLabel.Text = 'Pro & Consm';
            app.ProConsm = uieditfield(app.BasicSettings, 'numeric');
            app.ProConsm.ValueChangedFcn = createCallbackFcn(app, @ProConsmValueChanged, true);
            app.SupplyingSMOMsLabel = uilabel(app.BasicSettings);
            app.SupplyingSMOMsLabel.Text = 'Supplying SMOMs';
            app.SubstAdd = uieditfield(app.BasicSettings, 'numeric');
            app.SubstAdd.ValueChangedFcn = createCallbackFcn(app, @SubstAddValueChanged, true);
            app.InitialSMOMsLabel = uilabel(app.BasicSettings);
            app.InitialSMOMsLabel.Text = 'Initial SMOMs';
            app.SubstInitial = uieditfield(app.BasicSettings, 'numeric');
            app.SubstInitial.ValueChangedFcn = createCallbackFcn(app, @SubstInitialValueChanged, true);
            app.StartButton = uibutton(app.BasicSettings, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.MOVButton = uibutton(app.BasicSettings, 'push');
            app.MOVButton.ButtonPushedFcn = createCallbackFcn(app, @MOVButtonPushed, true);
            app.MOVButton.Text = 'MOV';
            app.OperatingSettings = uipanel(app.UIFigure);
            app.OperatingSettings.Title = 'Indicators Settings';
            app.ActualSpecies = uicheckbox(app.OperatingSettings);
            app.ActualSpecies.Text = 'Actual number of species';
            app.Title1 = uilabel(app.OperatingSettings);
            app.Title1.Text = 'Richness';
            app.SimpsonUseful = uicheckbox(app.OperatingSettings);
            app.SimpsonUseful.Text = 'Simpson effective species';
            app.ShannonDiversity = uicheckbox(app.OperatingSettings);
            app.ShannonDiversity.Text = 'Shannon diversity index';
            app.ShannonUseful = uicheckbox(app.OperatingSettings);
            app.ShannonUseful.Text = 'Shannon effective species';
            app.Title2 = uilabel(app.OperatingSettings);
            app.Title2.Text = 'Evenness';
            app.SimpsonEvenness = uicheckbox(app.OperatingSettings);
            app.SimpsonEvenness.Text = 'Simpson evenness index';
            app.ShannonEvenness = uicheckbox(app.OperatingSettings);
            app.ShannonEvenness.Text = 'Shannon evenness index';
            app.SuiJiButton = uibutton(app.UIFigure, 'push');
            app.SuiJiButton.ButtonPushedFcn = createCallbackFcn(app, @SuiJiButtonPushed, true);
            app.SuiJiButton.Text = {'Random'; 'Mix'};
            app.QuChuButton = uibutton(app.UIFigure, 'push');
            app.QuChuButton.ButtonPushedFcn = createCallbackFcn(app, @QuChuButtonPushed, true);
            app.QuChuButton.Text = {'Inter.'; 'Disturb.'};
            app.HuHuanButton = uibutton(app.UIFigure, 'push');
            app.HuHuanButton.ButtonPushedFcn = createCallbackFcn(app, @HuHuanButtonPushed, true);
            app.HuHuanButton.Text = {'Position'; 'Swap'};
            app.OperatingMonth = uieditfield(app.UIFigure, 'numeric');
            app.OperatingMonth.ValueChangedFcn = createCallbackFcn(app, @OperatingMonthValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CodeinAppDesigner

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end